classdef gui_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        OrientationTab                  matlab.ui.container.Tab
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        HoldingLabel                    matlab.ui.control.Label
        ModelparametersTextArea         matlab.ui.control.TextArea
        ModelparametersTextArea2        matlab.ui.control.TextArea
        NewLabel                        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        RunmodelButton                  matlab.ui.control.Button
        NoplotsButton                   matlab.ui.control.StateButton
        HoldimageButton                 matlab.ui.control.StateButton
        SetmodelparametersLabel         matlab.ui.control.Label
        Flowratem3hEditFieldLabel       matlab.ui.control.Label
        Flowratem3hEditField            matlab.ui.control.NumericEditField
        ThermalconductivityWmKEditFieldLabel  matlab.ui.control.Label
        ThermalconductivityWmKEditField  matlab.ui.control.NumericEditField
        HeatcapacityJKEditFieldLabel    matlab.ui.control.Label
        HeatcapacityJKEditField         matlab.ui.control.NumericEditField
        Densitykgm3EditFieldLabel       matlab.ui.control.Label
        Densitykgm3EditField            matlab.ui.control.NumericEditField
        RockpropertiesLabel             matlab.ui.control.Label
        Watertemperaturedeg_CEditFieldLabel  matlab.ui.control.Label
        Watertemperaturedeg_CEditField  matlab.ui.control.NumericEditField
        Rocktemperaturedeg_CEditFieldLabel  matlab.ui.control.Label
        Rocktemperaturedeg_CEditField   matlab.ui.control.NumericEditField
        FlowdurationyearsEditFieldLabel  matlab.ui.control.Label
        FlowdurationyearsEditField      matlab.ui.control.NumericEditField
        ClearallplotsButton             matlab.ui.control.Button
        LayoutTab                       matlab.ui.container.Tab
        GeometryOptionDropDown          matlab.ui.control.DropDown
        GeometryOptionDropDownLabel     matlab.ui.control.Label
        SelectnumberofusersMin1max4Label  matlab.ui.control.Label
        ChoosegeometryLabel             matlab.ui.control.Label
        Inflow1EditField                matlab.ui.control.NumericEditField
        Inflow1EditFieldLabel           matlab.ui.control.Label
        Inflow2EditField                matlab.ui.control.NumericEditField
        Inflow2EditFieldLabel           matlab.ui.control.Label
        Outflow1EditField               matlab.ui.control.NumericEditField
        Outflow1EditFieldLabel          matlab.ui.control.Label
        Outflow2EditField               matlab.ui.control.NumericEditField
        Outflow2EditFieldLabel          matlab.ui.control.Label
        PlotlayoutButton                matlab.ui.control.Button
        PipediametermEditFieldLabel     matlab.ui.control.Label
        PipediametermEditField          matlab.ui.control.NumericEditField
        Inflow3EditField                matlab.ui.control.NumericEditField
        Inflow3EditFieldLabel           matlab.ui.control.Label
        Inflow4EditField                matlab.ui.control.NumericEditField
        Inflow4EditFieldLabel           matlab.ui.control.Label
        Outflow3EditField               matlab.ui.control.NumericEditField
        Outflow3EditFieldLabel          matlab.ui.control.Label
        Outflow4EditField               matlab.ui.control.NumericEditField
        Outflow4EditFieldLabel          matlab.ui.control.Label
        UserAmountDropDown              matlab.ui.control.DropDown
        TextArea                        matlab.ui.control.TextArea
        TextArea2                       matlab.ui.control.TextArea
        SavefigureButton_2              matlab.ui.control.Button
        asEditFieldLabel_3              matlab.ui.control.Label
        FigureFileName2EditField        matlab.ui.control.EditField
        FigureFileType2DropDown         matlab.ui.control.DropDown
        FigureNumDropDown               matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        QlowrangeEditFieldLabel         matlab.ui.control.Label
        QlowrangeEditField              matlab.ui.control.NumericEditField
        SelectparameterrangesLabel      matlab.ui.control.Label
        QhighrangeEditFieldLabel        matlab.ui.control.Label
        QhighrangeEditField             matlab.ui.control.NumericEditField
        RunAnalysisButton_1             matlab.ui.control.Button
        highlowstepshouldnotgivedecimalnumberQlowrangecannotbe0Label  matlab.ui.control.Label
        Flowratem3hLabel                matlab.ui.control.Label
        QsteprangeDropDownLabel         matlab.ui.control.Label
        QsteprangeDropDown              matlab.ui.control.DropDown
        Watertemperaturedeg_CLabel      matlab.ui.control.Label
        RunAnalysisButton_2             matlab.ui.control.Button
        TwhighrangeEditFieldLabel       matlab.ui.control.Label
        TwhighrangeEditField            matlab.ui.control.NumericEditField
        TwlowrangeEditFieldLabel        matlab.ui.control.Label
        TwlowrangeEditField             matlab.ui.control.NumericEditField
        TwsteprangeDropDownLabel        matlab.ui.control.Label
        TwsteprangeDropDown             matlab.ui.control.DropDown
        RunAnalysisButton_3             matlab.ui.control.Button
        TrhighrangeEditFieldLabel       matlab.ui.control.Label
        TrhighrangeEditField            matlab.ui.control.NumericEditField
        TrlowrangeEditFieldLabel        matlab.ui.control.Label
        TrlowrangeEditField             matlab.ui.control.NumericEditField
        TrsteprangeDropDownLabel        matlab.ui.control.Label
        TrsteprangeDropDown             matlab.ui.control.DropDown
        Rocktemperaturedeg_CLabel       matlab.ui.control.Label
        RockthermalconductivityWmKLabel  matlab.ui.control.Label
        k_rlowrangeEditFieldLabel       matlab.ui.control.Label
        k_rlowrangeEditField            matlab.ui.control.NumericEditField
        k_rhighrangeEditFieldLabel      matlab.ui.control.Label
        k_rhighrangeEditField           matlab.ui.control.NumericEditField
        k_rsteprangeDropDownLabel       matlab.ui.control.Label
        k_rsteprangeDropDown            matlab.ui.control.DropDown
        RunAnalysisButton_4             matlab.ui.control.Button
        HeatcapacityrockJKLabel         matlab.ui.control.Label
        RunAnalysisButton_5             matlab.ui.control.Button
        Cp_rlowrangeEditFieldLabel      matlab.ui.control.Label
        Cp_rlowrangeEditField           matlab.ui.control.NumericEditField
        Cp_rhighrangeEditFieldLabel     matlab.ui.control.Label
        Cp_rhighrangeEditField          matlab.ui.control.NumericEditField
        Cp_rsteprangeDropDownLabel      matlab.ui.control.Label
        Cp_rsteprangeDropDown           matlab.ui.control.DropDown
        UITable                         matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        SavedataButton                  matlab.ui.control.Button
        TableFileTypeDropDown           matlab.ui.control.DropDown
        asEditFieldLabel                matlab.ui.control.Label
        TableFileNameEditField          matlab.ui.control.EditField
        SavefigureButton                matlab.ui.control.Button
        asEditFieldLabel_2              matlab.ui.control.Label
        FigureFileNameEditField         matlab.ui.control.EditField
        FigureFileTypeDropDown          matlab.ui.control.DropDown
        RunAnalysisButton_6             matlab.ui.control.Button
        FlowdurationyearsLabel          matlab.ui.control.Label
        nyrshighrangeEditFieldLabel     matlab.ui.control.Label
        nyrshighrangeEditField          matlab.ui.control.NumericEditField
        nyrslowrangeEditFieldLabel      matlab.ui.control.Label
        nyrslowrangeEditField           matlab.ui.control.NumericEditField
        nyrssteprangeDropDownLabel      matlab.ui.control.Label
        nyrssteprangeDropDown           matlab.ui.control.DropDown
        RunAnalysisButton_7             matlab.ui.control.Button
        PipediametermLabel              matlab.ui.control.Label
        dhighrangeEditFieldLabel        matlab.ui.control.Label
        dhighrangeEditField             matlab.ui.control.NumericEditField
        dlowrangeEditFieldLabel         matlab.ui.control.Label
        dlowrangeEditField              matlab.ui.control.NumericEditField
        dsteprangeDropDownLabel         matlab.ui.control.Label
        dsteprangeDropDown              matlab.ui.control.DropDown
        LayoutTab_2                     matlab.ui.container.Tab
        UIAxes7                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        p_table        % table with data for analysis
        q_in_table     % table with flow input location data
        q_out_table    % table with flow output location data
    end
    
    methods (Access = private)
        
        function run_model(app,runmodelButtonHandle,pbar)
            % set up progressbar
            [wbar,cleanup,runmodelButtonHandle] = setup_pbar(app,runmodelButtonHandle);
            % update progressbar
            n = 0.2;
            update_pbar(app,runmodelButtonHandle,wbar,n)
            
            % update progressbar window
            pbar.Value = .2;
            pbar.Message = 'Loading geometry and setting parameters';
            %pause(1) % optional, may increase visual effect of progressbar
            
            % set input variables and parameters defined by user
            [igeom,qset,Tf_ini,Tr,nyrs,k_r,Cp_r,rho_r,d,q_in,q_out] = read_input(app);
            
            % read in geometries:
            [nn, no, np, A12, A10, xo, x, Ho, q] = geometries(igeom,qset,q_in,q_out);
            
            % Array with diameter for each pipe
            d = d*ones(np,1);
            
            % Array with start & end node of each pipe:
            pipe_nodes  = zeros(np,2);  % array to store start & end node of each pipe
                                        % note the 'global' node numbering, including
                                        % fixed and free head nodes
            A102 = [A10 A12];           % merges table of fixed (A10) and unknown (A12) nodes
            [row,col] = find(A102==-1); % node with -1 indicates one end point of pipe
            pipe_nodes(row,1) = col;    % store that node nr in pipe_nodes array
            [row,col] = find(A102==1);  % node with 1 indicates the other end point
            pipe_nodes(row,2) = col;    % store that node nr in pipe_nodes array
        
            % Pipe lengths: 
            L   = zeros(np,1);
            xtotal = [xo;x];
            p1 = pipe_nodes(:, 1);
            p2 = pipe_nodes(:, 2);
            x1 = xtotal(p1,:);
            x2 = xtotal(p2,:);
            dx = x2-x1;
            L  = sqrt(dx(:,1).^2+dx(:,2).^2);
        
            % Initial rock temperature (in degC):   
            Tin    = Tf_ini*ones(np,1);
            
            % updatew progressbar
            n = 0.4;
            update_pbar(app,runmodelButtonHandle,wbar,n)
            
            % update progressbar window
            pbar.Value = .4;
            pbar.Message = 'Calculating flow distribution';
            %pause(1) % optional, may increase visual effect of progressbar
            
            % Calculate flow through pipe system:
            [H, Q]  = mineflow(nn, no, np, x, xo, A12, A10, Ho, q, L, d);
        
            %%% Setup pipe flow arrays:
            [pipe_nodes, npipes, node_pipes_out, node_pipes_in, Q] ...
                   = mine_array_setup(np, nn, no, A10, A12, pipe_nodes, q, Q);
        
            %%% Calculate temperature of pipe system:
            % set time at 'nyear' years:
            t      = 3600*24*365*nyrs;
            v      = Q ./ (pi*(d/2).^2);
            
            % update progressbar
            n = 0.6;
            update_pbar(app,runmodelButtonHandle,wbar,n)
            
            % update progressbar window
            pbar.Value = .6;
            pbar.Message = 'Calculating temperature distribution';
            %pause(1) % optional, may increase visual effect of progressbar
            
            [Tn, Tp]= mine_heat(t, d, L, Q, v, np, nn, no, Tf_ini, k_r, Cp_r, rho_r,...
                Tr, npipes, node_pipes_in, node_pipes_out, pipe_nodes,xtotal);
            % Output of routine mine_heat: 
            % - Tn, ordered as [x0;x] (i.e. first all fixed-head nodes, then the others
            % - Tp, as np-by-2 array, with Tp(:,1)=inflow T of pipe, and Tp(:,2) the
            %      outflow T   
            
            % update progressbar
            n = 0.8;
            update_pbar(app,runmodelButtonHandle,wbar,n)
            
            % update progressbar window
            pbar.Value = .8;
            pbar.Message = 'Plotting results';
            %pause(1) % optional, may increase visual effect of progressbar
            
            % plot results:
            if app.NoplotsButton.Value == true
                if app.HoldimageButton.Value == true
                    for i = 1:length(q_in)
                        app.TextArea.Value{i} = ['Outflow',num2str(i),' T = ', num2str(Tn(q_out{i}+1)), ' degC'];
                    end
                    app.ModelparametersTextArea.Value{1} = ['Geometry ', num2str(igeom)];
                    app.ModelparametersTextArea.Value{2} = ['Flowrate = ', num2str(qset*3600), ' m^3/h'];
                    app.ModelparametersTextArea.Value{3} = ['Rock T = ', num2str(Tr), ' degC'];
                    app.ModelparametersTextArea.Value{4} = ['Water T = ', num2str(Tf_ini), ' degC'];
                    app.ModelparametersTextArea.Value{5} = ['Thermal conductivity = ', num2str(k_r), ' W/(m*K)'];
                    app.ModelparametersTextArea.Value{6} = ['Heat capacity = ', num2str(Cp_r), ' J/K'];
                    app.ModelparametersTextArea.Value{7} = ['Density = ', num2str(rho_r), ' kg/m^3'];
                    app.ModelparametersTextArea.Value{8} = ['Flow duration = ', num2str(nyrs), ' years'];
                    app.ModelparametersTextArea.Value{9} = ['Pipe diameter = ', num2str(d(1)), ' m'];
                else
                    for i = 1:length(q_in)
                        app.TextArea2.Value{i} = ['Outflow',num2str(i),' T = ', num2str(Tn(q_out{i}+1)), ' degC'];
                    end
                    app.ModelparametersTextArea2.Value{1} = ['Geometry ', num2str(igeom)];
                    app.ModelparametersTextArea2.Value{2} = ['Flowrate = ', num2str(qset*3600), ' m^3/h'];
                    app.ModelparametersTextArea2.Value{3} = ['Rock T = ', num2str(Tr), ' degC'];
                    app.ModelparametersTextArea2.Value{4} = ['Water T = ', num2str(Tf_ini), ' degC'];
                    app.ModelparametersTextArea2.Value{5} = ['Thermal conductivity = ', num2str(k_r), ' W/(m*K)'];
                    app.ModelparametersTextArea2.Value{6} = ['Heat capacity = ', num2str(Cp_r), ' J/K'];
                    app.ModelparametersTextArea2.Value{7} = ['Density = ', num2str(rho_r), ' kg/m^3'];
                    app.ModelparametersTextArea2.Value{8} = ['Flow duration = ', num2str(nyrs), ' years'];
                    app.ModelparametersTextArea2.Value{9} = ['Pipe diameter = ', num2str(d(1)), ' m'];
                end
            else
                if app.HoldimageButton.Value == true
                    mine_plots_gui (app.UIAxes1, app.UIAxes3, igeom, xo, x, d, np, nn, pipe_nodes, Tp, Tn, Q, H, Ho,q_in,q_out,Tr)
                    % Tn(q_out1+1) is chosen because Tn, ordered as [x0;x] 
                    % (i.e. first all fixed-head nodes, then the others 
                    % So, Tn(1) is the fixed head node and since there is 1 
                    % fixed-head node and size(x0) = 1
                    % Therefore, the required node is chosen node + 1
                    for i = 1:length(q_in)
                        app.TextArea.Value{i} = ['Outflow',num2str(i),' T = ', num2str(Tn(q_out{i}+1)), ' degC'];
                    end
                    app.ModelparametersTextArea.Value{1} = ['Geometry ', num2str(igeom)];
                    app.ModelparametersTextArea.Value{2} = ['Flowrate = ', num2str(qset*3600), ' m^3/h'];
                    app.ModelparametersTextArea.Value{3} = ['Rock T = ', num2str(Tr), ' degC'];
                    app.ModelparametersTextArea.Value{4} = ['Water T = ', num2str(Tf_ini), ' degC'];
                    app.ModelparametersTextArea.Value{5} = ['Thermal conductivity = ', num2str(k_r), ' W/(m*K)'];
                    app.ModelparametersTextArea.Value{6} = ['Heat capacity = ', num2str(Cp_r), ' J/K'];
                    app.ModelparametersTextArea.Value{7} = ['Density = ', num2str(rho_r), ' kg/m^3'];
                    app.ModelparametersTextArea.Value{8} = ['Flow duration = ', num2str(nyrs), ' years'];
                    app.ModelparametersTextArea.Value{9} = ['Pipe diameter = ', num2str(d(1)), ' m'];
                else
                    mine_plots_gui (app.UIAxes2, app.UIAxes4, igeom, xo, x, d, np, nn, pipe_nodes, Tp, Tn, Q, H, Ho,q_in,q_out,Tr)
                    for i = 1:length(q_in)
                        app.TextArea2.Value{i} = ['Outflow',num2str(i),' T = ', num2str(Tn(q_out{i}+1)), ' degC'];
                    end
                    app.ModelparametersTextArea2.Value{1} = ['Geometry ', num2str(igeom)];
                    app.ModelparametersTextArea2.Value{2} = ['Flowrate = ', num2str(qset*3600), ' m^3/h'];
                    app.ModelparametersTextArea2.Value{3} = ['Rock T = ', num2str(Tr), ' degC'];
                    app.ModelparametersTextArea2.Value{4} = ['Water T = ', num2str(Tf_ini), ' degC'];
                    app.ModelparametersTextArea2.Value{5} = ['Thermal conductivity = ', num2str(k_r), ' W/(m*K)'];
                    app.ModelparametersTextArea2.Value{6} = ['Heat capacity = ', num2str(Cp_r), ' J/K'];
                    app.ModelparametersTextArea2.Value{7} = ['Density = ', num2str(rho_r), ' kg/m^3'];
                    app.ModelparametersTextArea2.Value{8} = ['Flow duration = ', num2str(nyrs), ' years'];
                    app.ModelparametersTextArea2.Value{9} = ['Pipe diameter = ', num2str(d(1)), ' m'];
                end
            end
            
            % update progressbar
            n = 1;
            update_pbar(app,runmodelButtonHandle,wbar,n)
            
            % update progressbar window
            pbar.Value = 1;
            pbar.Message = 'Finishing';
            %pause(1) % optional, may increase visual effect of progressbar
        end
        
        
        function run_model_2(app,plotlayoutButtonHandle,igeom,qset,d,q_in,q_out)
            % set up progressbar
            [wbar,cleanup,plotlayoutButtonHandle] = setup_pbar(app,plotlayoutButtonHandle);
            
            % update progressbar
            n = 0.1;
            update_pbar(app,plotlayoutButtonHandle,wbar,n)
            
            % read in geometries:
            [nn, no, np, A12, A10, xo, x, Ho, q] = geometries(igeom,qset,q_in,q_out);
            
            % update progressbar
            n = 0.2;
            update_pbar(app,plotlayoutButtonHandle,wbar,n)
            
            % Array with diameter for each pipe
            d = d*ones(np,1);
            
            % Array with start & end node of each pipe:
            pipe_nodes  = zeros(np,2);  % array to store start & end node of each pipe
                                        % note the 'global' node numbering, including
                                        %    fixed and free head nodes
            A102 = [A10 A12];           % merges table of fixed (A10) and unknown (A12) nodes
            [row,col] = find(A102==-1); % node with -1 indicates one end point of pipe
            pipe_nodes(row,1) = col;    % store that node nr in pipe_nodes array
            [row,col] = find(A102==1);  % node with 1 indicates the other end point
            pipe_nodes(row,2) = col;    % store that node nr in pipe_nodes array
            
            % update progressbar
            n = 0.3;
            update_pbar(app,plotlayoutButtonHandle,wbar,n)
            
            % Pipe lengths: 
            L   = zeros(np,1);
            xtotal = [xo;x];
            p1 = pipe_nodes(:, 1);
            p2 = pipe_nodes(:, 2);
            x1 = xtotal(p1,:);
            x2 = xtotal(p2,:);
            dx = x2-x1;
            L  = sqrt(dx(:,1).^2+dx(:,2).^2);
            
            % update progressbar
            n = 0.5;
            update_pbar(app,plotlayoutButtonHandle,wbar,n)
            
            if igeom == 8
                % big mine model shown on bigger figure
                layout_plot_gui(app.UIAxes7, nn, xo, x, d, np, pipe_nodes,q_in,q_out)
            else
                % small mine models shown on smaller figure
                layout_plot_gui(app.UIAxes5, nn, xo, x, d, np, pipe_nodes,q_in,q_out)
            end
            
            % update progressbar
            n = 1;
            update_pbar(app,plotlayoutButtonHandle,wbar,n)
            
        end
        
        function [Tn] = run_model_3(app,igeom,qset,Tf_ini,Tr,nyrs,k_r,Cp_r,rho_r,d,q_in,q_out)
            % read in geometries:
            [nn, no, np, A12, A10, xo, x, Ho, q] = geometries(igeom,qset,q_in,q_out);
            
            % Array with diameter for each pipe
            d = d*ones(np,1);
            
            % Array with start & end node of each pipe:
            pipe_nodes  = zeros(np,2);  % array to store start & end node of each pipe
                                        % note the 'global' node numbering, including
                                        %    fixed and free head nodes
            A102 = [A10 A12];           % merges table of fixed (A10) and unknown (A12) nodes
            [row,col] = find(A102==-1); % node with -1 indicates one end point of pipe
            pipe_nodes(row,1) = col;    % store that node nr in pipe_nodes array
            [row,col] = find(A102==1);  % node with 1 indicates the other end point
            pipe_nodes(row,2) = col;    % store that node nr in pipe_nodes array
        
            % Pipe lengths: 
            L   = zeros(np,1);
            xtotal = [xo;x];
            p1 = pipe_nodes(:, 1);
            p2 = pipe_nodes(:, 2);
            x1 = xtotal(p1,:);
            x2 = xtotal(p2,:);
            dx = x2-x1;
            L  = sqrt(dx(:,1).^2+dx(:,2).^2);
        
            % Initial rock temperature (in degC):   
            Tin    = Tf_ini*ones(np,1);   
            
            % Calculate flow through pipe system:
            [H, Q]  = mineflow(nn, no, np, x, xo, A12, A10, Ho, q, L, d);
        
            %%% Setup pipe flow arrays:
            [pipe_nodes, npipes, node_pipes_out, node_pipes_in, Q] ...
                   = mine_array_setup(np, nn, no, A10, A12, pipe_nodes, q, Q);
        
            %%% Calculate temperature of pipe system:
            % set time at 'nyear' years:
            t      = 3600*24*365*nyrs;
            v      = Q ./ (pi*(d/2).^2);
            
            [Tn, Tp]= mine_heat(t, d, L, Q, v, np, nn, no, Tf_ini, k_r, Cp_r, rho_r,...
                Tr, npipes, node_pipes_in, node_pipes_out, pipe_nodes,xtotal);
            % Output of routine mine_heat: 
            % - Tn, ordered as [x0;x] (i.e. first all fixed-head nodes, then the others
            % - Tp, as np-by-2 array, with Tp(:,1)=inflow T of pipe, and Tp(:,2) the
            %      outflow T   
            
        end
        
        function update_pbar(app,ButtonHandle,wbar,n)
            % Update progress bar 
            currentProg = min(round((size(wbar,2)-2)*(n)),size(wbar,2)-2); 
            ButtonHandle.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            ButtonHandle.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
            ButtonHandle.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
            % Pause to slow down animation (OPTIONAL)
            pause(.1)
        end
        
        function [wbar,cleanup,ButtonHandle] = setup_pbar(app,ButtonHandle)
            % Setup progress bar within button
            % Store original button text
            originalButtonText = ButtonHandle.Text;
            % When the function ends, return the original button state
            cleanup = onCleanup(@()set(ButtonHandle,'Text',originalButtonText,'Icon',''));
            % Change button name to "Processing"
            ButtonHandle.Text = 'Processing...';
            % Put text on top of icon
            ButtonHandle.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(ButtonHandle.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            ButtonHandle.Icon = wbar;
        end
        
        function run_analysis_gen(app,lowrangeName,highrangeName,steprangeName,VarXLabel,VarColName,val_num)
            % Run analysis using one changing variable while setting the
            % others constant
            
            % set up progress bar that tracks analysis iterations
            [wbar,cleanup,app.RunAnalysisButton_1] = ...
                setup_pbar(app,app.(sprintf('RunAnalysisButton_%d',val_num)));
            
            % clear figure
            cla(app.UIAxes6);
            
            % set input variables and parameters defined by user
            [igeom,qset,Tf_ini,Tr,nyrs,k_r,Cp_r,rho_r,d,q_in,q_out] = read_input(app);
            
            % define flowrate range for analysis for-loop
            var_low = app.(lowrangeName).Value;
            var_high = app.(highrangeName).Value;
            var_step = str2num(app.(steprangeName).Value);

            % define parameter vector containing name and value for each
            % parameter
            app.p_table = table({'Geometry';'Flow rate (m^3/h)';'Water T (deg_C)';...
                'Rock T (deg_C)';'Thermal conductivity (W/(m*K))';'Heat capacity (J/K)';...
                'Density (kg/m^3)';'Flow duration (yrs)';'Pipe diameter (m)'},...
                [igeom;qset*3600;Tf_ini;Tr;k_r;Cp_r;rho_r;nyrs;d],...
                'VariableNames',{'Parameters' 'Value'});
            
            % cells for naming and storing inflow outflow position
            app.q_in_table = cell(length(q_in),2);
            app.q_out_table = cell(length(q_in),2);
            
            % name and store locations of in and outflows
            for i = 1:length(q_in)
                inflowName  = sprintf('Inflow%d pos',i);
                outflowName = sprintf('Outflow%d pos',i);
                app.q_in_table(i,:)  = {inflowName q_in{i}};
                app.q_out_table(i,:) = {outflowName q_out{i}};
            end
            
            % transform cells into table for merging
            app.q_in_table = cell2table(app.q_in_table,'VariableNames',{'Parameters' 'Value'});
            app.q_out_table = cell2table(app.q_out_table,'VariableNames',{'Parameters' 'Value'});
            
            % merge parameter and inflow outflow location table
            app.p_table = [app.p_table;app.q_in_table;app.q_out_table];
            
            % define var_plot and T_plot for analysis plot
            var_plot = zeros((var_high-var_low)/var_step,1);
            T_plot   = cell(str2num(app.UserAmountDropDown.Value),1);
            for i = 1:str2num(app.UserAmountDropDown.Value)
                T_plot{i} = zeros((var_high-var_low)/var_step,1); 
            end
            
            % define i for var_plot T_plot indexing and updating progress bar
            i = 1;
            i_total = (var_high-var_low)/var_step;
            
            % for-loop that iterates over variable range set by user
            for varset = var_low:var_step:var_high
                % update progress bar
                update_pbar(app,app.(sprintf('RunAnalysisButton_%d',val_num)),wbar,i/i_total)
                switch val_num
                    case 1 % flow rate
                        varset = varset/3600; % calculations done with m^3/s
                        % run the model and extract Tn
                        [Tn] = run_model_3(app,igeom,varset,Tf_ini,Tr,...
                            nyrs,k_r,Cp_r,rho_r,d,q_in,q_out); 
                        var_plot(i) = varset;
                        varset = varset*3600; % convert back to m^3/h for user
                        % delete changing variable from constants table
                        if varset == var_high
                            app.p_table(2,:) = [];
                        end
                    case 2 % water temp
                        % run the model and extract Tn
                        [Tn] = run_model_3(app,igeom,qset,varset,Tr,...
                            nyrs,k_r,Cp_r,rho_r,d,q_in,q_out); 
                        var_plot(i) = varset;
                        % delete changing variable from constants table
                        if varset == var_high
                            app.p_table(3,:) = [];
                        end
                    case 3 % rock temp
                        % run the model and extract Tn
                        [Tn] = run_model_3(app,igeom,qset,Tf_ini,varset,...
                            nyrs,k_r,Cp_r,rho_r,d,q_in,q_out); 
                        var_plot(i) = varset;
                        % delete changing variable from constants table
                        if varset == var_high
                            app.p_table(4,:) = [];
                        end
                    case 4 % thermal conductivity
                        % run the model and extract Tn
                        [Tn] = run_model_3(app,igeom,qset,Tf_ini,Tr,...
                            nyrs,varset,Cp_r,rho_r,d,q_in,q_out); 
                        var_plot(i) = varset;
                        % delete changing variable from constants table
                        if varset == var_high
                            app.p_table(5,:) = [];
                        end
                    case 5 % specific heat
                        % run the model and extract Tn
                        [Tn] = run_model_3(app,igeom,qset,Tf_ini,Tr,...
                            nyrs,k_r,varset,rho_r,d,q_in,q_out); 
                        var_plot(i) = varset;
                        % delete changing variable from constants table
                        if varset == var_high
                            app.p_table(6,:) = [];
                        end
                    case 6 % flow duration
                        % run the model and extract Tn
                        [Tn] = run_model_3(app,igeom,qset,Tf_ini,Tr,...
                            varset,k_r,Cp_r,rho_r,d,q_in,q_out); 
                        var_plot(i) = varset;
                        % delete changing variable from constants table
                        if varset == var_high
                            app.p_table(8,:) = [];
                        end
                    case 7 % pipe diameter
                        % run the model and extract Tn
                        [Tn] = run_model_3(app,igeom,qset,Tf_ini,Tr,...
                            nyrs,k_r,Cp_r,rho_r,varset,q_in,q_out); 
                        var_plot(i) = varset;
                        % delete changing variable from constants table
                        if varset == var_high
                            app.p_table(9,:) = [];
                        end
                end
                % store outflow temperatures for plotting and results table
                for j = 1:str2num(app.UserAmountDropDown.Value)
                    T_plot{j}(i) = Tn(q_out{j}+1);
                end
                i = i+1;
            end
            
            % Plot temperature against analysis variable
            Legend = cell(str2num(app.UserAmountDropDown.Value),1);
            for i = 1:str2num(app.UserAmountDropDown.Value)
                plot(app.UIAxes6,var_plot,T_plot{i})
                Legend{i} = sprintf('T%d (^oC)',i);
                hold(app.UIAxes6,'on')
            end
            legend(app.UIAxes6,Legend)
            xlabel(app.UIAxes6,VarXLabel)
            ylabel(app.UIAxes6,'Temperature (^oC)')
            
            % create results table depending on user amount
            switch app.UserAmountDropDown.Value
                case '1'
                    app.UITable.Data = [var_plot T_plot{1}];
                    app.UITable.ColumnName = {VarColName 'T1 (deg_C)'};
                case '2'
                    app.UITable.Data = [var_plot T_plot{1} T_plot{2}];
                    app.UITable.ColumnName = {VarColName 'T1 (deg_C)' 'T2 (deg_C)'};
                case '3'
                    app.UITable.Data = [var_plot T_plot{1} T_plot{2} T_plot{3}];
                    app.UITable.ColumnName = {VarColName 'T1 (deg_C)' 'T2 (deg_C)' 'T3 (deg_C)'};
                case '4'
                    app.UITable.Data = [var_plot T_plot{1} T_plot{2} T_plot{3} T_plot{4}];
                    app.UITable.ColumnName = {VarColName 'T1 (deg_C)' 'T2 (deg_C)' 'T3 (deg_C)' 'T4 (deg_C)'};
            end
            app.UITable2.Data = app.p_table;
            app.UITable2.ColumnName = app.p_table.Properties.VariableNames;
        end
        
        function [igeom,qset,Tf_ini,Tr,nyrs,k_r,Cp_r,rho_r,d,q_in,q_out] = read_input(app)
            % define variables that were set in gui as parameters
            igeom  = str2num(app.GeometryOptionDropDown.Value); % select the geometry
            qset   = app.Flowratem3hEditField.Value/3600; %  set the flowrate at inflow and outflow nodes
            Tf_ini = app.Watertemperaturedeg_CEditField.Value; % water inflow temperature (degC)
            Tr     = app.Rocktemperaturedeg_CEditField.Value; % Initial rock temperature (degC)
            nyrs   = app.FlowdurationyearsEditField.Value; % flow duration in years
            k_r    = app.ThermalconductivityWmKEditField.Value; % rock thermal conductivity
            Cp_r   = app.HeatcapacityJKEditField.Value; % rock heat capacity
            rho_r  = app.Densitykgm3EditField.Value; % rock density
            d      = app.PipediametermEditField.Value; % pipe diameter (m)
            
            % create cells for storing inflow outflow positions
            q_in   = cell(str2num(app.UserAmountDropDown.Value),1);
            q_out  = cell(str2num(app.UserAmountDropDown.Value),1);
            
            % define node positions of inflows and outflows
            for i = 1:str2num(app.UserAmountDropDown.Value)
                q_in{i}  = app.(sprintf('Inflow%dEditField',i)).Value;
                q_out{i} = app.(sprintf('Outflow%dEditField',i)).Value;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Clear axes
            cla(app.UIAxes1);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            cla(app.UIAxes7);
        end

        % Value changed function: UserAmountDropDown
        function UserAmountDropDownValueChanged(app, event)
            % select the amount of users on the mine system
            % and hide edit fields if not in use
            % currently 1-4 users possible
            % more can be added, also need to change run model and 
            % analysis fucntions
            value = app.UserAmountDropDown.Value;
            switch value
                case '1'
                    app.Inflow2EditField.Visible = 'off';
                    app.Inflow2EditFieldLabel.Visible = 'off';
                    app.Inflow3EditField.Visible = 'off';
                    app.Inflow3EditFieldLabel.Visible = 'off';
                    app.Inflow4EditField.Visible = 'off';
                    app.Inflow4EditFieldLabel.Visible = 'off';
                    app.Outflow2EditField.Visible = 'off';
                    app.Outflow2EditFieldLabel.Visible = 'off';
                    app.Outflow3EditField.Visible = 'off';
                    app.Outflow3EditFieldLabel.Visible = 'off';
                    app.Outflow4EditField.Visible = 'off';
                    app.Outflow4EditFieldLabel.Visible = 'off';
                case '2'
                    app.Inflow2EditField.Visible = 'on';
                    app.Inflow2EditFieldLabel.Visible = 'on';
                    app.Inflow3EditField.Visible = 'off';
                    app.Inflow3EditFieldLabel.Visible = 'off';
                    app.Inflow4EditField.Visible = 'off';
                    app.Inflow4EditFieldLabel.Visible = 'off';
                    app.Outflow2EditField.Visible = 'on';
                    app.Outflow2EditFieldLabel.Visible = 'on';
                    app.Outflow3EditField.Visible = 'off';
                    app.Outflow3EditFieldLabel.Visible = 'off';
                    app.Outflow4EditField.Visible = 'off';
                    app.Outflow4EditFieldLabel.Visible = 'off';
                case '3'
                    app.Inflow2EditField.Visible = 'on';
                    app.Inflow2EditFieldLabel.Visible = 'on';
                    app.Inflow3EditField.Visible = 'on';
                    app.Inflow3EditFieldLabel.Visible = 'on';
                    app.Inflow4EditField.Visible = 'off';
                    app.Inflow4EditFieldLabel.Visible = 'off';
                    app.Outflow2EditField.Visible = 'on';
                    app.Outflow2EditFieldLabel.Visible = 'on';
                    app.Outflow3EditField.Visible = 'on';
                    app.Outflow3EditFieldLabel.Visible = 'on';
                    app.Outflow4EditField.Visible = 'off';
                    app.Outflow4EditFieldLabel.Visible = 'off';
                case '4'
                    app.Inflow2EditField.Visible = 'on';
                    app.Inflow2EditFieldLabel.Visible = 'on';
                    app.Inflow3EditField.Visible = 'on';
                    app.Inflow3EditFieldLabel.Visible = 'on';
                    app.Inflow4EditField.Visible = 'on';
                    app.Inflow4EditFieldLabel.Visible = 'on';
                    app.Outflow2EditField.Visible = 'on';
                    app.Outflow2EditFieldLabel.Visible = 'on';
                    app.Outflow3EditField.Visible = 'on';
                    app.Outflow3EditFieldLabel.Visible = 'on';
                    app.Outflow4EditField.Visible = 'on';
                    app.Outflow4EditFieldLabel.Visible = 'on';
            end
        end

        % Button pushed function: SavefigureButton
        function SavefigureButtonPushed(app, event)
            % save figure from temp and variable analysis
            fileName = append(app.FigureFileNameEditField.Value,app.FigureFileTypeDropDown.Value);
            disp(fileName)
            exportgraphics(app.UIAxes6,fileName);
        end

        % Button pushed function: SavefigureButton_2
        function SavefigureButton_2Pushed(app, event)
            % save figure 1, 2, 3 or 4 on run model tab
            fileName = append(app.FigureFileName2EditField.Value,app.FigureFileType2DropDown.Value);
            
            exportgraphics(app.(sprintf('UIAxes%d',str2num(app.FigureNumDropDown.Value))),fileName);
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            % save constants table and append results table
            fileName = append(app.TableFileNameEditField.Value,app.TableFileTypeDropDown.Value);
            
            writetable(app.p_table,fileName);
            
            switch app.UserAmountDropDown.Value
                case '1'
                    T = table(app.UITable.Data(:,1),app.UITable.Data(:,2),...
                        'VariableNames',app.UITable.ColumnName);
                case '2'
                    T = table(app.UITable.Data(:,1),app.UITable.Data(:,2),app.UITable.Data(:,3),...
                        'VariableNames',app.UITable.ColumnName);
                case '3'
                    T = table(app.UITable.Data(:,1),app.UITable.Data(:,2),app.UITable.Data(:,3),...
                        app.UITable.Data(:,4),'VariableNames',app.UITable.ColumnName);
                case '4'
                    T = table(app.UITable.Data(:,1),app.UITable.Data(:,2),app.UITable.Data(:,3),...
                        app.UITable.Data(:,4),app.UITable.Data(:,5),...
                        'VariableNames',app.UITable.ColumnName);
            end
            
            writetable(T,fileName,'WriteMode','Append','WriteVariableNames',true);
        end

        % Button pushed function: RunmodelButton
        function RunmodelButtonPushed(app, event)
            % set up progressbar window
            fig = uifigure;
            pbar = uiprogressdlg(fig,'Title','Please Wait',...
            'Message','Opening the application');
            pause(.5)
            
            % run the model and plots axes 
            run_model(app,app.RunmodelButton,pbar)
            
            close(pbar)
            close(fig)
        end

        % Button pushed function: PlotlayoutButton
        function PlotlayoutButtonPushed(app, event)
            % define variables that were set in gui as parameters
            igeom = str2num(app.GeometryOptionDropDown.Value); % select the geometry
            qset  = app.Flowratem3hEditField.Value/3600; %  set the flowrate at inflow and outflow nodes
            d      = app.PipediametermEditField.Value; % pipe diameter (m)
            
            q_in   = cell(str2num(app.UserAmountDropDown.Value),1);
            q_out  = cell(str2num(app.UserAmountDropDown.Value),1);
            
            % define node positions of inflows and outflows
            for i = 1:str2num(app.UserAmountDropDown.Value)
                q_in{i}  = app.(sprintf('Inflow%dEditField',i)).Value;
                q_out{i} = app.(sprintf('Outflow%dEditField',i)).Value;
            end
            
            run_model_2(app,app.PlotlayoutButton,igeom,qset,d,q_in,q_out);
            
            if igeom == 8;
                cla(app.UIAxes5,'reset');
            end
        end

        % Button pushed function: ClearallplotsButton
        function ClearallplotsButtonPushed(app, event)
            % clear axes
            cla(app.UIAxes1,'reset');
            cla(app.UIAxes2,'reset');
            cla(app.UIAxes3,'reset');
            cla(app.UIAxes4,'reset');
            % clear text fields
            app.ModelparametersTextArea.Value = {'Constant parameters'};
            app.ModelparametersTextArea2.Value = {'Constant parameters'};
            app.TextArea.Value = {'Outflow temperatures'};
            app.TextArea2.Value = {'Outflow temperatures'};
        end

        % Button pushed function: RunAnalysisButton_1
        function RunAnalysisButton_1Pushed(app, event)
            % Flow rate analysis
            lowrangeName = 'QlowrangeEditField';
            highrangeName = 'QhighrangeEditField';
            steprangeName = 'QsteprangeDropDown';
            
            VarXLabel = 'Flowrate (m^3/s)';
            VarColName = 'Flowrate (m^3/s)';
            
            val_num = 1; % tracks which variable is used for analysis
            
            run_analysis_gen(app,lowrangeName,highrangeName,steprangeName,VarXLabel,VarColName,val_num)
        end

        % Button pushed function: RunAnalysisButton_2
        function RunAnalysisButton_2Pushed(app, event)
            % Water temperature analysis
            lowrangeName = 'TwlowrangeEditField';
            highrangeName = 'TwhighrangeEditField';
            steprangeName = 'TwsteprangeDropDown';
            
            VarXLabel = 'Water temperature (^oC)';
            VarColName = 'Water T (deg_C)';
            
            val_num = 2; % tracks which variable is used for analysis
            
            run_analysis_gen(app,lowrangeName,highrangeName,steprangeName,VarXLabel,VarColName,val_num)
        end

        % Button pushed function: RunAnalysisButton_3
        function RunAnalysisButton_3Pushed(app, event)
            % Rock temperature analysis
            lowrangeName = 'TrlowrangeEditField';
            highrangeName = 'TrhighrangeEditField';
            steprangeName = 'TrsteprangeDropDown';
            
            VarXLabel = 'Rock temperature (^oC)';
            VarColName = 'Rock T (deg_C)';
            
            val_num = 3; % tracks which variable is used for analysis
            
            run_analysis_gen(app,lowrangeName,highrangeName,steprangeName,VarXLabel,VarColName,val_num)
        end

        % Button pushed function: RunAnalysisButton_4
        function RunAnalysisButton_4Pushed(app, event)
            % Thermal conductivity analysis
            lowrangeName = 'k_rlowrangeEditField';
            highrangeName = 'k_rhighrangeEditField';
            steprangeName = 'k_rsteprangeDropDown';
            
            VarXLabel = 'Thermal conductivity (W/(m*K))';
            VarColName = 'Thermal conductivity (W/(m*K))';
            
            val_num = 4; % tracks which variable is used for analysis
            
            run_analysis_gen(app,lowrangeName,highrangeName,steprangeName,VarXLabel,VarColName,val_num)
        end

        % Button pushed function: RunAnalysisButton_5
        function RunAnalysisButton_5Pushed(app, event)
            % Specific heat analysis
            lowrangeName = 'Cp_rlowrangeEditField';
            highrangeName = 'Cp_rhighrangeEditField';
            steprangeName = 'Cp_rsteprangeDropDown';
            
            VarXLabel = 'Heat capacity (J/K)';
            VarColName = 'Heat capacity (J/K)';
            
            val_num = 5; % tracks which variable is used for analysis
            
            run_analysis_gen(app,lowrangeName,highrangeName,steprangeName,VarXLabel,VarColName,val_num)
        end

        % Button pushed function: RunAnalysisButton_6
        function RunAnalysisButton_6Pushed(app, event)
            % Flow duration analysis
            % Specific heat analysis
            lowrangeName = 'nyrslowrangeEditField';
            highrangeName = 'nyrshighrangeEditField';
            steprangeName = 'nyrssteprangeDropDown';
            
            VarXLabel = 'Flow duration (years)';
            VarColName = 'Flow duration (years)';
            
            val_num = 6; % tracks which variable is used for analysis
            
            run_analysis_gen(app,lowrangeName,highrangeName,steprangeName,VarXLabel,VarColName,val_num)
        end

        % Button pushed function: RunAnalysisButton_7
        function RunAnalysisButton_7Pushed(app, event)
            % Pipe diameter analysis
            % Specific heat analysis
            lowrangeName = 'dlowrangeEditField';
            highrangeName = 'dhighrangeEditField';
            steprangeName = 'dsteprangeDropDown';
            
            VarXLabel = 'Pipe diameter (m)';
            VarColName = 'Pipe diameter (m)';
            
            val_num = 7; % tracks which variable is used for analysis
            
            run_analysis_gen(app,lowrangeName,highrangeName,steprangeName,VarXLabel,VarColName,val_num)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.OrientationTab = uitab(app.TabGroup2);
            app.OrientationTab.Title = 'Orientation';
            app.UIAxes1 = uiaxes(app.OrientationTab);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes2 = uiaxes(app.OrientationTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes3 = uiaxes(app.OrientationTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes4 = uiaxes(app.OrientationTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes5 = uiaxes(app.OrientationTab);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.HoldingLabel = uilabel(app.OrientationTab);
            app.HoldingLabel.Text = 'Holding';
            app.ModelparametersTextArea = uitextarea(app.OrientationTab);
            app.ModelparametersTextArea.Value = {'Constant parameters'};
            app.ModelparametersTextArea2 = uitextarea(app.OrientationTab);
            app.ModelparametersTextArea2.Value = {'Constant parameters'};
            app.NewLabel = uilabel(app.OrientationTab);
            app.NewLabel.Text = 'New';
            app.TabGroup = uitabgroup(app.OrientationTab);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.RunmodelButton = uibutton(app.ParametersTab, 'push');
            app.RunmodelButton.ButtonPushedFcn = createCallbackFcn(app, @RunmodelButtonPushed, true);
            app.RunmodelButton.Text = 'Run model';
            app.NoplotsButton = uibutton(app.ParametersTab, 'state');
            app.NoplotsButton.Text = 'No plots';
            app.HoldimageButton = uibutton(app.ParametersTab, 'state');
            app.HoldimageButton.Text = 'Hold image';
            app.SetmodelparametersLabel = uilabel(app.ParametersTab);
            app.SetmodelparametersLabel.Text = 'Set model parameters';
            app.Flowratem3hEditFieldLabel = uilabel(app.ParametersTab);
            app.Flowratem3hEditFieldLabel.Text = 'Flow rate (m^3/h)';
            app.Flowratem3hEditField = uieditfield(app.ParametersTab, 'numeric');
            app.Flowratem3hEditField.Value = 10;
            app.ThermalconductivityWmKEditFieldLabel = uilabel(app.ParametersTab);
            app.ThermalconductivityWmKEditFieldLabel.Text = 'Thermal conductivity (W/(m*K))';
            app.ThermalconductivityWmKEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ThermalconductivityWmKEditField.Value = 3;
            app.HeatcapacityJKEditFieldLabel = uilabel(app.ParametersTab);
            app.HeatcapacityJKEditFieldLabel.Text = 'Heat capacity (J/K)';
            app.HeatcapacityJKEditField = uieditfield(app.ParametersTab, 'numeric');
            app.HeatcapacityJKEditField.Value = 900;
            app.Densitykgm3EditFieldLabel = uilabel(app.ParametersTab);
            app.Densitykgm3EditFieldLabel.Text = 'Density (kg/m^3)';
            app.Densitykgm3EditField = uieditfield(app.ParametersTab, 'numeric');
            app.Densitykgm3EditField.Value = 2300;
            app.RockpropertiesLabel = uilabel(app.ParametersTab);
            app.RockpropertiesLabel.Text = 'Rock properties';
            app.Watertemperaturedeg_CEditFieldLabel = uilabel(app.ParametersTab);
            app.Watertemperaturedeg_CEditFieldLabel.Text = 'Water temperature (deg_C)';
            app.Watertemperaturedeg_CEditField = uieditfield(app.ParametersTab, 'numeric');
            app.Watertemperaturedeg_CEditField.Value = 1;
            app.Rocktemperaturedeg_CEditFieldLabel = uilabel(app.ParametersTab);
            app.Rocktemperaturedeg_CEditFieldLabel.Text = 'Rock temperature (deg_C)';
            app.Rocktemperaturedeg_CEditField = uieditfield(app.ParametersTab, 'numeric');
            app.Rocktemperaturedeg_CEditField.Value = 25;
            app.FlowdurationyearsEditFieldLabel = uilabel(app.ParametersTab);
            app.FlowdurationyearsEditFieldLabel.Text = 'Flow duration (years)';
            app.FlowdurationyearsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.FlowdurationyearsEditField.Value = 1;
            app.ClearallplotsButton = uibutton(app.ParametersTab, 'push');
            app.ClearallplotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallplotsButtonPushed, true);
            app.ClearallplotsButton.Text = 'Clear all plots';
            app.LayoutTab = uitab(app.TabGroup);
            app.LayoutTab.Title = 'Layout';
            app.GeometryOptionDropDown = uidropdown(app.LayoutTab);
            app.GeometryOptionDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '101', '102', '103'};
            app.GeometryOptionDropDown.Value = '101';
            app.GeometryOptionDropDownLabel = uilabel(app.LayoutTab);
            app.GeometryOptionDropDownLabel.Text = 'Geometry Option';
            app.SelectnumberofusersMin1max4Label = uilabel(app.LayoutTab);
            app.SelectnumberofusersMin1max4Label.Text = {'Select number of users'; 'Min 1 max 4'};
            app.ChoosegeometryLabel = uilabel(app.LayoutTab);
            app.ChoosegeometryLabel.Text = 'Choose geometry';
            app.Inflow1EditField = uieditfield(app.LayoutTab, 'numeric');
            app.Inflow1EditField.Value = 1;
            app.Inflow1EditFieldLabel = uilabel(app.LayoutTab);
            app.Inflow1EditFieldLabel.Text = 'Inflow1';
            app.Inflow2EditField = uieditfield(app.LayoutTab, 'numeric');
            app.Inflow2EditField.Visible = 'off';
            app.Inflow2EditField.Value = 1;
            app.Inflow2EditFieldLabel = uilabel(app.LayoutTab);
            app.Inflow2EditFieldLabel.Visible = 'off';
            app.Inflow2EditFieldLabel.Text = 'Inflow2 ';
            app.Outflow1EditField = uieditfield(app.LayoutTab, 'numeric');
            app.Outflow1EditField.Value = 10;
            app.Outflow1EditFieldLabel = uilabel(app.LayoutTab);
            app.Outflow1EditFieldLabel.Text = 'Outflow1';
            app.Outflow2EditField = uieditfield(app.LayoutTab, 'numeric');
            app.Outflow2EditField.Visible = 'off';
            app.Outflow2EditField.Value = 10;
            app.Outflow2EditFieldLabel = uilabel(app.LayoutTab);
            app.Outflow2EditFieldLabel.Visible = 'off';
            app.Outflow2EditFieldLabel.Text = 'Outflow2';
            app.PlotlayoutButton = uibutton(app.LayoutTab, 'push');
            app.PlotlayoutButton.ButtonPushedFcn = createCallbackFcn(app, @PlotlayoutButtonPushed, true);
            app.PlotlayoutButton.Text = 'Plot layout';
            app.PipediametermEditFieldLabel = uilabel(app.LayoutTab);
            app.PipediametermEditFieldLabel.Text = 'Pipe diameter (m)';
            app.PipediametermEditField = uieditfield(app.LayoutTab, 'numeric');
            app.PipediametermEditField.Value = 2;
            app.Inflow3EditField = uieditfield(app.LayoutTab, 'numeric');
            app.Inflow3EditField.Visible = 'off';
            app.Inflow3EditField.Value = 1;
            app.Inflow3EditFieldLabel = uilabel(app.LayoutTab);
            app.Inflow3EditFieldLabel.Visible = 'off';
            app.Inflow3EditFieldLabel.Text = 'Inflow3 ';
            app.Inflow4EditField = uieditfield(app.LayoutTab, 'numeric');
            app.Inflow4EditField.Visible = 'off';
            app.Inflow4EditField.Value = 1;
            app.Inflow4EditFieldLabel = uilabel(app.LayoutTab);
            app.Inflow4EditFieldLabel.Visible = 'off';
            app.Inflow4EditFieldLabel.Text = 'Inflow4 ';
            app.Outflow3EditField = uieditfield(app.LayoutTab, 'numeric');
            app.Outflow3EditField.Visible = 'off';
            app.Outflow3EditField.Value = 10;
            app.Outflow3EditFieldLabel = uilabel(app.LayoutTab);
            app.Outflow3EditFieldLabel.Visible = 'off';
            app.Outflow3EditFieldLabel.Text = 'Outflow3';
            app.Outflow4EditField = uieditfield(app.LayoutTab, 'numeric');
            app.Outflow4EditField.Visible = 'off';
            app.Outflow4EditField.Value = 10;
            app.Outflow4EditFieldLabel = uilabel(app.LayoutTab);
            app.Outflow4EditFieldLabel.Visible = 'off';
            app.Outflow4EditFieldLabel.Text = 'Outflow4';
            app.UserAmountDropDown = uidropdown(app.LayoutTab);
            app.UserAmountDropDown.Items = {'1', '2', '3', '4'};
            app.UserAmountDropDown.ValueChangedFcn = createCallbackFcn(app, @UserAmountDropDownValueChanged, true);
            app.UserAmountDropDown.Value = '1';
            app.TextArea = uitextarea(app.OrientationTab);
            app.TextArea.Value = {'Outflow temperatures'};
            app.TextArea2 = uitextarea(app.OrientationTab);
            app.TextArea2.Value = {'Outflow temperatures'};
            app.SavefigureButton_2 = uibutton(app.OrientationTab, 'push');
            app.SavefigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavefigureButton_2Pushed, true);
            app.SavefigureButton_2.Text = 'Save figure';
            app.asEditFieldLabel_3 = uilabel(app.OrientationTab);
            app.asEditFieldLabel_3.Text = 'as';
            app.FigureFileName2EditField = uieditfield(app.OrientationTab, 'text');
            app.FigureFileName2EditField.Value = 'Untitled';
            app.FigureFileType2DropDown = uidropdown(app.OrientationTab);
            app.FigureFileType2DropDown.Items = {'.pdf', '.png', '.jpg'};
            app.FigureFileType2DropDown.Value = '.jpg';
            app.FigureNumDropDown = uidropdown(app.OrientationTab);
            app.FigureNumDropDown.Items = {'1', '2', '3', '4'};
            app.FigureNumDropDown.Value = '2';
            app.Label = uilabel(app.OrientationTab);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.OrientationTab);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.OrientationTab);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.OrientationTab);
            app.Label_4.Text = '4';
            app.AnalysisTab = uitab(app.TabGroup2);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxes6 = uiaxes(app.AnalysisTab);
            title(app.UIAxes6, '')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.QlowrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.QlowrangeEditFieldLabel.Text = 'Q low range';
            app.QlowrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.QlowrangeEditField.Value = 1;
            app.SelectparameterrangesLabel = uilabel(app.AnalysisTab);
            app.SelectparameterrangesLabel.Text = 'Select parameter ranges';
            app.QhighrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.QhighrangeEditFieldLabel.Text = 'Q high range';
            app.QhighrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.QhighrangeEditField.Value = 1;
            app.RunAnalysisButton_1 = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton_1.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_1Pushed, true);
            app.RunAnalysisButton_1.Text = 'Run Analysis';
            app.highlowstepshouldnotgivedecimalnumberQlowrangecannotbe0Label = uilabel(app.AnalysisTab);
            app.highlowstepshouldnotgivedecimalnumberQlowrangecannotbe0Label.Text = {'(high - low)/step should not give decimal number'; 'Q low range - cannot be 0'};
            app.Flowratem3hLabel = uilabel(app.AnalysisTab);
            app.Flowratem3hLabel.Text = 'Flow rate (m^3/h)';
            app.QsteprangeDropDownLabel = uilabel(app.AnalysisTab);
            app.QsteprangeDropDownLabel.Text = 'Q step range';
            app.QsteprangeDropDown = uidropdown(app.AnalysisTab);
            app.QsteprangeDropDown.Items = {'1', '5', '10', '100'};
            app.QsteprangeDropDown.Value = '1';
            app.Watertemperaturedeg_CLabel = uilabel(app.AnalysisTab);
            app.Watertemperaturedeg_CLabel.Text = 'Water temperature (deg_C)';
            app.RunAnalysisButton_2 = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_2Pushed, true);
            app.RunAnalysisButton_2.Text = 'Run Analysis';
            app.TwhighrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.TwhighrangeEditFieldLabel.Text = 'Tw high range';
            app.TwhighrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.TwlowrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.TwlowrangeEditFieldLabel.Text = 'Tw low range';
            app.TwlowrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.TwsteprangeDropDownLabel = uilabel(app.AnalysisTab);
            app.TwsteprangeDropDownLabel.Text = 'Tw step range';
            app.TwsteprangeDropDown = uidropdown(app.AnalysisTab);
            app.TwsteprangeDropDown.Items = {'0.1', '1', '10'};
            app.TwsteprangeDropDown.Value = '1';
            app.RunAnalysisButton_3 = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_3Pushed, true);
            app.RunAnalysisButton_3.Text = 'Run Analysis';
            app.TrhighrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.TrhighrangeEditFieldLabel.Text = 'Tr high range';
            app.TrhighrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.TrlowrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.TrlowrangeEditFieldLabel.Text = 'Tr low range';
            app.TrlowrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.TrsteprangeDropDownLabel = uilabel(app.AnalysisTab);
            app.TrsteprangeDropDownLabel.Text = 'Tr step range';
            app.TrsteprangeDropDown = uidropdown(app.AnalysisTab);
            app.TrsteprangeDropDown.Items = {'0.1', '1', '10'};
            app.TrsteprangeDropDown.Value = '1';
            app.Rocktemperaturedeg_CLabel = uilabel(app.AnalysisTab);
            app.Rocktemperaturedeg_CLabel.Text = 'Rock temperature (deg_C)';
            app.RockthermalconductivityWmKLabel = uilabel(app.AnalysisTab);
            app.RockthermalconductivityWmKLabel.Text = 'Rock thermal conductivity (W/(m*K))';
            app.k_rlowrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.k_rlowrangeEditFieldLabel.Text = 'k_r low range';
            app.k_rlowrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.k_rhighrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.k_rhighrangeEditFieldLabel.Text = 'k_r high range';
            app.k_rhighrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.k_rsteprangeDropDownLabel = uilabel(app.AnalysisTab);
            app.k_rsteprangeDropDownLabel.Text = 'k_r step range';
            app.k_rsteprangeDropDown = uidropdown(app.AnalysisTab);
            app.k_rsteprangeDropDown.Items = {'0.1', '1', '10'};
            app.k_rsteprangeDropDown.Value = '0.1';
            app.RunAnalysisButton_4 = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_4Pushed, true);
            app.RunAnalysisButton_4.Text = 'Run Analysis';
            app.HeatcapacityrockJKLabel = uilabel(app.AnalysisTab);
            app.HeatcapacityrockJKLabel.Text = 'Heat capacity rock (J/K)';
            app.RunAnalysisButton_5 = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton_5.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_5Pushed, true);
            app.RunAnalysisButton_5.Text = 'Run Analysis';
            app.Cp_rlowrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.Cp_rlowrangeEditFieldLabel.Text = 'Cp_r low range';
            app.Cp_rlowrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.Cp_rhighrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.Cp_rhighrangeEditFieldLabel.Text = 'Cp_r high range';
            app.Cp_rhighrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.Cp_rsteprangeDropDownLabel = uilabel(app.AnalysisTab);
            app.Cp_rsteprangeDropDownLabel.Text = 'Cp_r step range';
            app.Cp_rsteprangeDropDown = uidropdown(app.AnalysisTab);
            app.Cp_rsteprangeDropDown.Items = {'1', '10', '50', '100'};
            app.Cp_rsteprangeDropDown.Value = '50';
            app.UITable = uitable(app.AnalysisTab);
            app.UITable.ColumnName = {'X'; 'T (deg_C)'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.AnalysisTab);
            app.UITable2.ColumnName = {'Parameters'; 'Values'};
            app.UITable2.RowName = {};
            app.SavedataButton = uibutton(app.AnalysisTab, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.TableFileTypeDropDown = uidropdown(app.AnalysisTab);
            app.TableFileTypeDropDown.Items = {'.dat', '.xlsx', '.csv'};
            app.TableFileTypeDropDown.Value = '.dat';
            app.asEditFieldLabel = uilabel(app.AnalysisTab);
            app.asEditFieldLabel.Text = 'as';
            app.TableFileNameEditField = uieditfield(app.AnalysisTab, 'text');
            app.TableFileNameEditField.Value = 'Untitled';
            app.SavefigureButton = uibutton(app.AnalysisTab, 'push');
            app.SavefigureButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigureButtonPushed, true);
            app.SavefigureButton.Text = 'Save figure';
            app.asEditFieldLabel_2 = uilabel(app.AnalysisTab);
            app.asEditFieldLabel_2.Text = 'as';
            app.FigureFileNameEditField = uieditfield(app.AnalysisTab, 'text');
            app.FigureFileNameEditField.Value = 'Untitled';
            app.FigureFileTypeDropDown = uidropdown(app.AnalysisTab);
            app.FigureFileTypeDropDown.Items = {'.pdf', '.png', '.jpg'};
            app.FigureFileTypeDropDown.Value = '.jpg';
            app.RunAnalysisButton_6 = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton_6.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_6Pushed, true);
            app.RunAnalysisButton_6.Text = 'Run Analysis';
            app.FlowdurationyearsLabel = uilabel(app.AnalysisTab);
            app.FlowdurationyearsLabel.Text = 'Flow duration (years)';
            app.nyrshighrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.nyrshighrangeEditFieldLabel.Text = 'nyrs high range';
            app.nyrshighrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.nyrslowrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.nyrslowrangeEditFieldLabel.Text = 'nyrs low range';
            app.nyrslowrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.nyrssteprangeDropDownLabel = uilabel(app.AnalysisTab);
            app.nyrssteprangeDropDownLabel.Text = 'nyrs step range';
            app.nyrssteprangeDropDown = uidropdown(app.AnalysisTab);
            app.nyrssteprangeDropDown.Items = {'1', '5', '10', '100'};
            app.nyrssteprangeDropDown.Value = '1';
            app.RunAnalysisButton_7 = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton_7.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_7Pushed, true);
            app.RunAnalysisButton_7.Text = 'Run Analysis';
            app.PipediametermLabel = uilabel(app.AnalysisTab);
            app.PipediametermLabel.Text = 'Pipe diameter (m)';
            app.dhighrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.dhighrangeEditFieldLabel.Text = 'd high range';
            app.dhighrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.dlowrangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.dlowrangeEditFieldLabel.Text = 'd low range';
            app.dlowrangeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.dsteprangeDropDownLabel = uilabel(app.AnalysisTab);
            app.dsteprangeDropDownLabel.Text = 'd step range';
            app.dsteprangeDropDown = uidropdown(app.AnalysisTab);
            app.dsteprangeDropDown.Items = {'0.1', '1'};
            app.dsteprangeDropDown.Value = '1';
            app.LayoutTab_2 = uitab(app.TabGroup2);
            app.LayoutTab_2.Title = 'Layout';
            app.UIAxes7 = uiaxes(app.LayoutTab_2);
            title(app.UIAxes7, '')
            xlabel(app.UIAxes7, '')
            ylabel(app.UIAxes7, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end